classdef yer_istasyonu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes2   matlab.ui.control.UIAxes
        UIAxes    matlab.ui.control.UIAxes
    end

methods (Access = private)

    % =========================================================================
    % 1. MOUSE SÜRÜKLEME MATEMATİK MOTORU
    % =========================================================================
    function MouseSuruklemeMatematikMotoru(app, src)
        ud = app.UIAxes.UserData;
        if ~isfield(ud, 'IsDragging') || ~ud.IsDragging
            return;
        end

        currPt = src.CurrentPoint;
        lastPt = ud.LastPoint;
        diffPt = currPt - lastPt;

        app.UIAxes.UserData.MouseYaw   = ud.MouseYaw   + diffPt(1) * 0.005;
        app.UIAxes.UserData.MousePitch = ud.MousePitch - diffPt(2) * 0.005;
        app.UIAxes.UserData.LastPoint  = currPt;
    end

    % =========================================================================
    % 2. TELEMETRİ GÜNCELLEME (Timer'a Bağlı - 25 FPS)
  
    function TelemetriVerisiGuncelle(app)
        app.UIAxes2.UserData.Zaman = app.UIAxes2.UserData.Zaman + 0.04;
        t = app.UIAxes2.UserData.Zaman;

        guncel_irtifa = 120 * t - 4.9 * t^2;
        if guncel_irtifa < 0, guncel_irtifa = 0; end

        ud = app.UIAxes.UserData;
        if isfield(ud, 'MouseYaw')
            myaw   = ud.MouseYaw;
            mpitch = ud.MousePitch;
        else
            myaw = 0; mpitch = 0;
        end

        roll       = 0;
        pitch_auto = 0;
        yaw_auto   = 0;

        R_auto  = makehgtform('zrotate', roll) * ...
                  makehgtform('xrotate', pitch_auto) * ...
                  makehgtform('yrotate', yaw_auto);
        R_mouse = makehgtform('yrotate', myaw) * ...
                  makehgtform('xrotate', mpitch);

        app.UIAxes.UserData.Grup.Matrix = R_mouse * R_auto;

        app.UIAxes2.UserData.ZamanVerileri(end+1) = t;
        app.UIAxes2.UserData.IrtifaVerileri(end+1) = guncel_irtifa;

        plot(app.UIAxes2, ...
             app.UIAxes2.UserData.ZamanVerileri, ...
             app.UIAxes2.UserData.IrtifaVerileri, ...
             'LineWidth', 2, 'Color', [0 0.8 0]);
        title(app.UIAxes2, ['Anlık İrtifa: ' num2str(round(guncel_irtifa)) ' Metre']);
        grid(app.UIAxes2, 'on');

        drawnow limitrate;
    end

    % Mouse aşağı 
    function OnMouseDown(app, src)
        app.UIAxes.UserData.IsDragging = true;
        app.UIAxes.UserData.LastPoint  = src.CurrentPoint;
    end

    % Mouse bırakma
    function OnMouseUp(app)
        app.UIAxes.UserData.IsDragging = false;
    end

end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
% --- A. ROKET GEOMETRİSİ (80 Nokta Çözünürlük) ---
[X_silindir, Y_silindir, Z_silindir] = cylinder([1 1], 80);
Z_silindir = Z_silindir * 6;

L_burun = 3; r_taban = 1;
rho = (r_taban^2 + L_burun^2) / (2 * r_taban);
x_ogiv = linspace(0, L_burun, 40);
r_ogiv = sqrt(rho^2 - x_ogiv.^2) + r_taban - rho;
[X_koni, Y_koni, Z_koni] = cylinder(r_ogiv, 80);
Z_koni = Z_koni * L_burun + 6;

[X_noz, Y_noz, Z_noz] = cylinder([1 0.7], 80);
Z_noz = Z_noz * 0.6 - 0.6;

[X_ates, Y_ates, Z_ates] = cylinder([0.7 0], 80);
Z_ates = Z_ates * 2.5 - 3.1;

hold(app.UIAxes, 'on');

app.UIAxes.UserData.Grup = hgtransform('Parent', app.UIAxes);

h_govde = surface(app.UIAxes, X_silindir, Y_silindir, Z_silindir, ...
    'Parent', app.UIAxes.UserData.Grup, ...
    'FaceColor', [0.8 0.1 0.1], 'EdgeColor', 'none', 'FaceLighting', 'phong');
h_burun = surface(app.UIAxes, X_koni, Y_koni, Z_koni, ...
    'Parent', app.UIAxes.UserData.Grup, ...
    'FaceColor', [0.1 0.2 0.7], 'EdgeColor', 'none', 'FaceLighting', 'phong');
h_nozul = surface(app.UIAxes, X_noz, Y_noz, Z_noz, ...
    'Parent', app.UIAxes.UserData.Grup, ...
    'FaceColor', [0.3 0.3 0.3], 'EdgeColor', 'none', 'FaceLighting', 'phong');
surface(app.UIAxes, X_ates, Y_ates, Z_ates, ...
    'Parent', app.UIAxes.UserData.Grup, ...
    'FaceColor', [1 0.4 0], 'EdgeColor', 'none', 'FaceLighting', 'none', ...
    'FaceAlpha', 0.4, 'AmbientStrength', 1.0);

material([h_govde, h_burun, h_nozul], 'shiny');

yazi = 'SİMURG ROKET';
yazi_z_baslangic = 6.0;
for i = 1:length(yazi)
    text(app.UIAxes, 1.03, 0, yazi_z_baslangic - (i * 0.35), yazi(i), ...
        'Parent', app.UIAxes.UserData.Grup, ...
        'Color', [0.3 0.3 0.3], 'FontSize', 11, 'FontWeight', 'bold', ...
        'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
end

siyah_renk = [0.12 0.12 0.12];
patch_opts = {'Parent', app.UIAxes.UserData.Grup, ...
              'FaceColor', siyah_renk, 'EdgeColor', 'none', 'FaceLighting', 'phong'};
patch(app.UIAxes, 'XData', [1 2.5 2.2 1],    'YData', [0 0 0 0],   'ZData', [0.3 0.8 1.8 2.4], patch_opts{:});
patch(app.UIAxes, 'XData', [-1 -2.5 -2.2 -1], 'YData', [0 0 0 0],  'ZData', [0.3 0.8 1.8 2.4], patch_opts{:});
patch(app.UIAxes, 'XData', [0 0 0 0], 'YData', [1 2.5 2.2 1],      'ZData', [0.3 0.8 1.8 2.4], patch_opts{:});
patch(app.UIAxes, 'XData', [0 0 0 0], 'YData', [-1 -2.5 -2.2 -1],  'ZData', [0.3 0.8 1.8 2.4], patch_opts{:});

axis(app.UIAxes, 'equal');
box(app.UIAxes, 'on');
grid(app.UIAxes, 'on');
app.UIAxes.Color     = [1 1 1];
app.UIAxes.GridColor = [0.85 0.85 0.85];
app.UIFigure.Color   = [1 1 1];

app.UIAxes.Interactions    = [];
app.UIAxes.Toolbar.Visible = 'off';
app.UIAxes.Projection      = 'perspective';
app.UIAxes.CameraPosition  = [20 20 5];
app.UIAxes.CameraTarget    = [0 0 4];
app.UIAxes.CameraUpVector  = [0 0 1];

xlim(app.UIAxes, [-8 8]);
ylim(app.UIAxes, [-8 8]);
zlim(app.UIAxes, [-10 15]);

delete(findall(app.UIAxes, 'Type', 'light'));
light(app.UIAxes, 'Position', [10 10 20], 'Style', 'local', 'Color', [1 1 1]);

app.UIAxes.UserData.IsDragging = false;
app.UIAxes.UserData.MouseYaw   = 0;
app.UIAxes.UserData.MousePitch = 0;
app.UIAxes.UserData.LastPoint  = [0 0];

app.UIFigure.WindowButtonDownFcn   = @(src, ~) app.OnMouseDown(src);
app.UIFigure.WindowButtonUpFcn     = @(~, ~)   app.OnMouseUp();
app.UIFigure.WindowButtonMotionFcn = @(src, ~)  app.MouseSuruklemeMatematikMotoru(src);

app.UIAxes2.Color     = [0.05 0.05 0.1];
app.UIAxes2.XColor    = [0 0.8 0.4];
app.UIAxes2.YColor    = [0 0.8 0.4];
app.UIAxes2.GridColor = [0 0.8 0.4];
app.UIAxes2.UserData.Zaman          = 0;
app.UIAxes2.UserData.IrtifaVerileri = [];
app.UIAxes2.UserData.ZamanVerileri  = [];

app.UIAxes.UserData.Timer = timer( ...
    'ExecutionMode', 'fixedRate', ...
    'Period', 0.04, ...
    'TimerFcn', @(~,~) app.TelemetriVerisiGuncelle());
start(app.UIAxes.UserData.Timer);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = yer_istasyonu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end